classdef csv_reader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        DatosTab                      matlab.ui.container.Tab
        GridLayout                    matlab.ui.container.GridLayout
        Image3                        matlab.ui.control.Image
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        CargarCSVButton               matlab.ui.control.Button
        GrficasoperacionalesZX300Tab  matlab.ui.container.Tab
        GridLayout2                   matlab.ui.container.GridLayout
        GeneralPanel                  matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        VermapaButton                 matlab.ui.control.Button
        CoordanadasEditField          matlab.ui.control.EditField
        CoordanadasEditFieldLabel     matlab.ui.control.Label
        EstadoEditField               matlab.ui.control.EditField
        EstadoEditFieldLabel          matlab.ui.control.Label
        HumedadPanel                  matlab.ui.container.Panel
        UIAxes3                       matlab.ui.control.UIAxes
        TemperaturaPanel              matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        VoltajePanel                  matlab.ui.container.Panel
        UIAxes1                       matlab.ui.control.UIAxes
        VientosTab                    matlab.ui.container.Tab
        GridLayout4                   matlab.ui.container.GridLayout
        EjexDropDown_2                matlab.ui.control.DropDown
        EjexDropDown_2Label           matlab.ui.control.Label
        EjexDropDown                  matlab.ui.control.DropDown
        EjexDropDownLabel             matlab.ui.control.Label
        CargarButton_2                matlab.ui.control.Button
        CargarButton                  matlab.ui.control.Button
        EjeyDropDown_2                matlab.ui.control.DropDown
        EjeyDropDown_2Label           matlab.ui.control.Label
        EjeyDropDown                  matlab.ui.control.DropDown
        EjeyDropDownLabel             matlab.ui.control.Label
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        DialogApp                   % Dialog box app
        archivo_def = 'datos/ejemplo.CSV'
    end

    methods (Access = public)
    
        function [datos, datos_funcionamiento] = cargar_datos(app, archivo)
            % Store inputs as properties

            archivo = string(archivo);
            
            app.archivo_def = archivo;
            
            % Cargar CSV

            % Poner el formato de fecha
            opts = detectImportOptions(archivo);
            opts = setvaropts(opts, 'TimeAndDate' ,'InputFormat','dd/MM/uuuu HH:mm:ss');

            datos = readtable(archivo, opts);
            clc % Limpiar la terminal

            % Eliminar ultima fina de datos
            datos(:, end) = [];
            
            % Eliminar columnas de la tabla
            datos.Reference = [];
            datos.Timestamp_s_ = [];
            datos.Info_Flags = [];
            
            % Creación de tabla con parámetros de funcinamiento del sensor
            TimeAndDate = datos.TimeAndDate;
            StatusFlags = datos.StatusFlags;
            Battery_V_ = datos.Battery_V_;
            Generator_V_ = datos.Generator_V_;
            UpperTemp__C_ = datos.UpperTemp__C_;
            LowerTemp__C_ = datos.LowerTemp__C_;
            PodHumidity___ = datos.PodHumidity___;
            GPS = datos.GPS;
            
            datos_funcionamiento = table(TimeAndDate, StatusFlags, ...
                Battery_V_, Generator_V_, UpperTemp__C_, LowerTemp__C_, ...
                PodHumidity___, GPS);
            
            datos.StatusFlags = [];
            datos.Battery_V_ = [];
            datos.Generator_V_ = [];
            datos.UpperTemp__C_ = [];
            datos.LowerTemp__C_ = [];
            datos.PodHumidity___ = [];
            datos.GPS = [];
            
            % Re-enable the Plot Options button
            app.CargarCSVButton.Enable = 'on';
        end

        function graf_op(app, info)
            
            % Primera gráfica
            yyaxis(app.UIAxes1, 'left')
            plot(app.UIAxes1, info.TimeAndDate, ...
                info.Battery_V_,'b--', 'LineWidth', 1)
            ylabel(app.UIAxes1, 'V bat')
            yyaxis(app.UIAxes1, 'right')
            plot(app.UIAxes1, info.TimeAndDate, ...
                info.Generator_V_, 'r--', 'LineWidth', 1)
            grid(app.UIAxes1, "minor")
            title(app.UIAxes1, 'Voltaje')
            xlabel(app.UIAxes1, 'Fecha')
            ylabel(app.UIAxes1, 'V gen')
            legend(app.UIAxes1, 'Batería', 'Generador')
            
            % Segunda gráfica
            yyaxis(app.UIAxes2, 'left')
            plot(app.UIAxes2, info.TimeAndDate, ...
                info.LowerTemp__C_,'b--', 'LineWidth', 1)
            ylabel(app.UIAxes2, 'T min')
            yyaxis(app.UIAxes2, 'right')
            plot(app.UIAxes2, info.TimeAndDate, ...
                info.UpperTemp__C_, 'r--', 'LineWidth', 1)
            grid(app.UIAxes2, "minor")
            title(app.UIAxes2, 'Temperaturas de funcionamiento')
            xlabel(app.UIAxes2, 'Fecha')
            ylabel(app.UIAxes2, 'T max')
            legend(app.UIAxes2, 'T max', 'T min')
            
            % Tercera gráfica
            plot(app.UIAxes3, info.TimeAndDate, ...
                info.PodHumidity___, 'r--', 'LineWidth', 1)
            grid(app.UIAxes3, "minor")
            title(app.UIAxes3, 'Humedad')
            xlabel(app.UIAxes3, 'Fecha')
            ylabel(app.UIAxes3, 'Humedad')
            
            app.CoordanadasEditField.Value = info.GPS{end};


            % Re-enable 
            % app.CargarCSVButton.Enable = 'on';
        end

        function check(app, info)
            for ii = 1:length(info.StatusFlags)
                txt = string(info.StatusFlags{ii});
                if txt == "Fully Operational"
                    app.EstadoEditField.Value = 'Todo funciona correctamente';
                    app.EstadoEditField.FontColor = 'g';
                else
                    app.EstadoEditField.Value = "Error de algun sistema. Hora: " ...
                        + string(info.TimeAndDate(ii));
                    app.EstadoEditField.FontColor = 'r';
                end
            end

            % Re-enable 
            app.CargarCSVButton.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [datos, datos_funcionamiento] = cargar_datos(app, app.archivo_def);
            
            graf_op(app, datos_funcionamiento)
            check(app, datos_funcionamiento)


            app.EjeyDropDown.Items = datos.Properties.VariableDescriptions;
            app.EjeyDropDown_2.Items = datos.Properties.VariableDescriptions;
            app.EjexDropDown.Items = datos.Properties.VariableDescriptions;
            app.EjexDropDown_2.Items = datos.Properties.VariableDescriptions;


            app.EjeyDropDown.Value = datos.Properties.VariableDescriptions(14);
            app.EjeyDropDown_2.Value = datos.Properties.VariableDescriptions(14);
            app.EjexDropDown.Value = datos.Properties.VariableDescriptions(1);
            app.EjexDropDown_2.Value = datos.Properties.VariableDescriptions(1);   
        end

        % Button pushed function: CargarCSVButton
        function CargarCSVButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.CargarCSVButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = ventana_emergente(app);
        end

        % Close request function: UIFigure
        function CSVAppCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogApp)
            delete(app)
        end

        % Button pushed function: VermapaButton
        function VermapaButtonPushed(app, event)
            [~, datos_funcionamiento] = cargar_datos(app, app.archivo_def);
            
            gx = geoaxes;

            long = zeros(1, length(datos_funcionamiento.GPS));
            lat = zeros(1, length(datos_funcionamiento.GPS));
            for i = 1:length(datos_funcionamiento.GPS)
                coor = datos_funcionamiento.GPS{i};
                contador = 1;
                for s = coor
                    if s == " "
                        disp('LLega')
                        long(i) = str2double(coor(1 : contador-1));
                        lat(i) = str2double(coor(contador+1 : end));
                    end
                contador = 1 + contador;
                end
            end
            
            % dinámico
%             for ll = 1:length(long)
%                 geoplot(gx, long(ll), lat(ll), 'r*')
%                 gx.Basemap = 'satellite';
%                 hold on
%             end
%             hold off

                geoplot(gx, long(end), lat(end), 'r*')
                gx.Basemap = 'satellite';
        end

        % Button pushed function: CargarButton
        function CargarButtonPushed(app, event)
            [datos, ~] = cargar_datos(app, app.archivo_def);
            
    
            for i = 1:length(datos.Properties.VariableDescriptions)
                ii = string(datos.Properties.VariableDescriptions{i});
                if string(app.EjeyDropDown.Value) == ii
                    for j = 1:length(datos.Properties.VariableDescriptions)
                        jj = string(datos.Properties.VariableDescriptions{j});
                        if string(app.EjexDropDown.Value) == jj
                            disp(datos(:, j))
                            disp(datos(:, i))
                            plot(app.UIAxes4, datos{:, j}, ...
                                datos{:, i}, 'b-.', 'LineWidth',1)
                            grid(app.UIAxes4, "minor")
                            xlabel(app.UIAxes4, app.EjexDropDown.Value)
                            ylabel(app.UIAxes4, app.EjeyDropDown.Value)
                            title(app.UIAxes4, app.EjeyDropDown.Value)
                        end
                    end
                end
            end


%             completar

        end

        % Button pushed function: CargarButton_2
        function CargarButton_2Pushed(app, event)
            [datos, ~] = cargar_datos(app, app.archivo_def);
            
            for i = 1:length(datos.Properties.VariableDescriptions)
                ii = string(datos.Properties.VariableDescriptions{i});
                if string(app.EjeyDropDown_2.Value) == ii
                    for j = 1:length(datos.Properties.VariableDescriptions)
                        jj = string(datos.Properties.VariableDescriptions{j});
                        if string(app.EjexDropDown_2.Value) == jj
                            disp(datos(:, j))
                            disp(datos(:, i))
                            plot(app.UIAxes5, datos{:, j}, ...
                                datos{:, i}, 'r-.', 'LineWidth',1)
                            grid(app.UIAxes5, "minor")
                            xlabel(app.UIAxes5, app.EjexDropDown_2.Value)
                            ylabel(app.UIAxes5, app.EjeyDropDown_2.Value)
                            title(app.UIAxes5, app.EjeyDropDown_2.Value)
                        end
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @CSVAppCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DatosTab = uitab(app.TabGroup);
            app.DatosTab.Title = 'Datos';
            app.GridLayout = uigridlayout(app.DatosTab);
            app.CargarCSVButton = uibutton(app.GridLayout, 'push');
            app.CargarCSVButton.ButtonPushedFcn = createCallbackFcn(app, @CargarCSVButtonPushed, true);
            app.CargarCSVButton.Text = 'Cargar CSV';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = 'ZX300.jpg';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'unileon.png';
            app.Image3 = uiimage(app.GridLayout);
            app.Image3.ImageSource = 'Escudo-Escuela-Leon_logo.png';
            app.GrficasoperacionalesZX300Tab = uitab(app.TabGroup);
            app.GrficasoperacionalesZX300Tab.Title = 'Gráficas operacionales ZX300';
            app.GridLayout2 = uigridlayout(app.GrficasoperacionalesZX300Tab);
            app.VoltajePanel = uipanel(app.GridLayout2);
            app.VoltajePanel.Title = 'Voltaje';
            app.UIAxes1 = uiaxes(app.VoltajePanel);
            title(app.UIAxes1, 'Title')
            xlabel(app.UIAxes1, 'X')
            ylabel(app.UIAxes1, 'Y')
            zlabel(app.UIAxes1, 'Z')
            app.TemperaturaPanel = uipanel(app.GridLayout2);
            app.TemperaturaPanel.Title = 'Temperatura';
            app.UIAxes2 = uiaxes(app.TemperaturaPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HumedadPanel = uipanel(app.GridLayout2);
            app.HumedadPanel.Title = 'Humedad';
            app.UIAxes3 = uiaxes(app.HumedadPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.GeneralPanel = uipanel(app.GridLayout2);
            app.GeneralPanel.Title = 'General';
            app.GridLayout3 = uigridlayout(app.GeneralPanel);
            app.EstadoEditFieldLabel = uilabel(app.GridLayout3);
            app.EstadoEditFieldLabel.Text = 'Estado';
            app.EstadoEditField = uieditfield(app.GridLayout3, 'text');
            app.CoordanadasEditFieldLabel = uilabel(app.GridLayout3);
            app.CoordanadasEditFieldLabel.Text = 'Coordanadas';
            app.CoordanadasEditField = uieditfield(app.GridLayout3, 'text');
            app.VermapaButton = uibutton(app.GridLayout3, 'push');
            app.VermapaButton.ButtonPushedFcn = createCallbackFcn(app, @VermapaButtonPushed, true);
            app.VermapaButton.Text = 'Ver mapa';
            app.VientosTab = uitab(app.TabGroup);
            app.VientosTab.Title = 'Vientos';
            app.GridLayout4 = uigridlayout(app.VientosTab);
            app.UIAxes5 = uiaxes(app.GridLayout4);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout4);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.EjeyDropDownLabel = uilabel(app.GridLayout4);
            app.EjeyDropDownLabel.Text = 'Eje y';
            app.EjeyDropDown = uidropdown(app.GridLayout4);
            app.EjeyDropDown_2Label = uilabel(app.GridLayout4);
            app.EjeyDropDown_2Label.Text = 'Eje y';
            app.EjeyDropDown_2 = uidropdown(app.GridLayout4);
            app.CargarButton = uibutton(app.GridLayout4, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @CargarButtonPushed, true);
            app.CargarButton.Text = 'Cargar';
            app.CargarButton_2 = uibutton(app.GridLayout4, 'push');
            app.CargarButton_2.ButtonPushedFcn = createCallbackFcn(app, @CargarButton_2Pushed, true);
            app.CargarButton_2.Text = 'Cargar';
            app.EjexDropDownLabel = uilabel(app.GridLayout4);
            app.EjexDropDownLabel.Text = 'Eje x';
            app.EjexDropDown = uidropdown(app.GridLayout4);
            app.EjexDropDown_2Label = uilabel(app.GridLayout4);
            app.EjexDropDown_2Label.Text = 'Eje x';
            app.EjexDropDown_2 = uidropdown(app.GridLayout4);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = csv_reader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end