classdef ventana_emergente < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Label                           matlab.ui.control.Label
        AceptarButton                   matlab.ui.control.Button
        NombredelarchivoEditField       matlab.ui.control.EditField
        NombredelarchivoEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp   % Main app object
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, csv_reader)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = csv_reader;
        end

        % Button pushed function: AceptarButton
        function AceptarButtonPushed(app, event)
            % Call main app's public function
            path = "datos/" + string(app.NombredelarchivoEditField.Value);

            [~, datos_funcionamiento] = cargar_datos(app.CallingApp, path);
            
            graf_op(app.CallingApp, datos_funcionamiento)

            check(app.CallingApp, datos_funcionamiento)
            % Delete the dialog box
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.NombredelarchivoEditFieldLabel = uilabel(app.GridLayout);
            app.NombredelarchivoEditFieldLabel.Text = 'Nombre del archivo';
            app.NombredelarchivoEditField = uieditfield(app.GridLayout, 'text');
            app.AceptarButton = uibutton(app.GridLayout, 'push');
            app.AceptarButton.ButtonPushedFcn = createCallbackFcn(app, @AceptarButtonPushed, true);
            app.AceptarButton.Text = 'Aceptar';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'El archivo debe situarse en una carpeta denominada "datos". '; 'Los datos deben estar en formato  ".csv"'; 'Por defecto usa unos datos de ejemplo'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ventana_emergente(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end